classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IndicederfractionEditField      matlab.ui.control.NumericEditField
        IndicederfractionEditFieldLabel  matlab.ui.control.Label
        LongueurdondenmEditField        matlab.ui.control.NumericEditField
        LongueurdondenmEditFieldLabel   matlab.ui.control.Label
        TemperatureKEditField           matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel      matlab.ui.control.Label
        Coefficientdiffusioncm1EditField  matlab.ui.control.NumericEditField
        Coefficientdiffusioncm1EditFieldLabel  matlab.ui.control.Label
        Coefficientabsorptioncm1EditField  matlab.ui.control.NumericEditField
        Coefficientabsorptioncm1EditFieldLabel  matlab.ui.control.Label
        PropOptiquesUO2Label            matlab.ui.control.Label
        HauteurcmEditField_2            matlab.ui.control.NumericEditField
        HauteurcmEditField_2Label       matlab.ui.control.Label
        RayoncmEditField                matlab.ui.control.NumericEditField
        RayoncmEditFieldLabel           matlab.ui.control.Label
        GomtriePastilleLabel            matlab.ui.control.Label
        GomtriecubodeLabel              matlab.ui.control.Label
        HauteurcmEditField              matlab.ui.control.NumericEditField
        HauteurcmLabel                  matlab.ui.control.Label
        LongueurcmEditField             matlab.ui.control.NumericEditField
        LongueurcmEditFieldLabel        matlab.ui.control.Label
        ResolutionnbvoxelcoteEditField  matlab.ui.control.NumericEditField
        ResolutionnbvoxelcoteLabel      matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        pEditField                      matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        TracerButton                    matlab.ui.control.Button
        ResultatsTextArea               matlab.ui.control.TextArea
        ResultatsTextAreaLabel          matlab.ui.control.Label
        LancersimuButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        Gx % has to be defined so we can call app.Gx
        NA % same reason
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gx = [];
            app.NA = [];
        end

        % Value changed function: HauteurcmEditField
        function HauteurcmEditFieldValueChanged(app, event)
            value = app.HauteurcmEditField.Value;
            app.HauteurcmEditField_2.Limits = [0 value];
        end

        % Value changed function: LongueurcmEditField
        function LongueurcmEditFieldValueChanged(app, event)
            value = app.LongueurcmEditField.Value;
            app.RayoncmEditField.Limits = [0 value/2];
        end

        % Button pushed function: LancersimuButton
        function LancersimuButtonPushed(app, event)
            app.ResultatsTextArea.Value = 'La simulation Monte-Carlo est en train de tourner, cela peut prendre une minute...';
            drawnow; % So that the Resultat text area is updated immediately, otherwise it takes too long
            super = contains(app.Switch.Value, 'Super');
            [Gloc, NAloc, intensity] = sim(app.ResolutionnbvoxelcoteEditField.Value, app.LongueurcmEditField.Value, ...
                app.HauteurcmEditField.Value, app.RayoncmEditField.Value, app.HauteurcmEditField_2.Value, ...
                app.LongueurdondenmEditField.Value, app.Coefficientabsorptioncm1EditField.Value, ...
                app.Coefficientdiffusioncm1EditField.Value, app.IndicederfractionEditField.Value, ...
                app.TemperatureKEditField.Value, super, app.pEditField.Value); % loc as in local, to not confuse the local and global variables
            [maxNA, maxNAindex] = max(NAloc(:));
            [maxNAx, maxNAy, maxNAz] = ind2sub(size(NAloc), maxNAindex);
            result = sprintf("La valeur maximum de l'absorption est %.2f atteinte pour x = %.0f, y = %.0f, z = %.0f\n", maxNA, maxNAx, maxNAy, maxNAz);
            app.ResultatsTextArea.Value = result;
            Gxloc = Gloc(1,:);
            absorption_data_at_z_y = squeeze(NAloc(:,100,maxNAz,:));
            plot(app.UIAxes, Gxloc, absorption_data_at_z_y);
            title_str = sprintf('Absorption à Y=100 and Z=%d', maxNAz);
            app.UIAxes.Title.String = title_str;
            plot(app.UIAxes2, intensity);
            app.Gx = Gxloc;
            app.NA = NAloc;
        end

        % Button pushed function: TracerButton
        function TracerButtonPushed(app, event)
            y_index = app.YEditField.Value;
            z_index = app.ZEditField.Value;
            absorption_data_at_z_y = squeeze(app.NA(:,y_index,z_index,:));
            plot(app.UIAxes, app.Gx, absorption_data_at_z_y);
            title_str = sprintf('Absorption à Y=%d and Z=%d', y_index, z_index);
            app.UIAxes.Title.String = title_str;
        end

        % Value changed function: ResolutionnbvoxelcoteEditField
        function ResolutionnbvoxelcoteEditFieldValueChanged(app, event)
            value = app.ResolutionnbvoxelcoteEditField.Value;
            app.ZEditField.Limits = [1 value];
            app.YEditField.Limits = [1 value];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {465, 465, 465};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {465, 465};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 222};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResolutionnbvoxelcoteLabel = uilabel(app.LeftPanel);
            app.ResolutionnbvoxelcoteLabel.Text = 'Resolution (nb voxel cote)';
            app.ResolutionnbvoxelcoteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ResolutionnbvoxelcoteEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionnbvoxelcoteEditFieldValueChanged, true);
            app.ResolutionnbvoxelcoteEditField.Value = 200;
            app.LongueurcmEditFieldLabel = uilabel(app.LeftPanel);
            app.LongueurcmEditFieldLabel.Text = 'Longueur (cm)';
            app.LongueurcmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongueurcmEditField.ValueChangedFcn = createCallbackFcn(app, @LongueurcmEditFieldValueChanged, true);
            app.LongueurcmEditField.Value = 1.5;
            app.HauteurcmLabel = uilabel(app.LeftPanel);
            app.HauteurcmLabel.Text = 'Hauteur (cm)   ';
            app.HauteurcmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HauteurcmEditField.ValueChangedFcn = createCallbackFcn(app, @HauteurcmEditFieldValueChanged, true);
            app.HauteurcmEditField.Value = 0.3;
            app.GomtriecubodeLabel = uilabel(app.LeftPanel);
            app.GomtriecubodeLabel.Text = 'Géométrie cuboïde';
            app.GomtriePastilleLabel = uilabel(app.LeftPanel);
            app.GomtriePastilleLabel.Text = 'Géométrie Pastille';
            app.RayoncmEditFieldLabel = uilabel(app.LeftPanel);
            app.RayoncmEditFieldLabel.Text = 'Rayon (cm)';
            app.RayoncmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RayoncmEditField.Value = 0.7;
            app.HauteurcmEditField_2Label = uilabel(app.LeftPanel);
            app.HauteurcmEditField_2Label.Text = 'Hauteur (cm)';
            app.HauteurcmEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.HauteurcmEditField_2.Value = 0.1;
            app.PropOptiquesUO2Label = uilabel(app.LeftPanel);
            app.PropOptiquesUO2Label.Text = 'Prop Optiques UO2';
            app.Coefficientabsorptioncm1EditFieldLabel = uilabel(app.LeftPanel);
            app.Coefficientabsorptioncm1EditFieldLabel.Text = 'Coefficient absorption cm^-1';
            app.Coefficientabsorptioncm1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Coefficientabsorptioncm1EditField.Value = 500;
            app.Coefficientdiffusioncm1EditFieldLabel = uilabel(app.LeftPanel);
            app.Coefficientdiffusioncm1EditFieldLabel.Text = 'Coefficient diffusion cm^-1';
            app.Coefficientdiffusioncm1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Coefficientdiffusioncm1EditField.Value = 0.1;
            app.TemperatureKEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperatureKEditField.Limits = [273.15 3120];
            app.TemperatureKEditField.Value = 310.5;
            app.LongueurdondenmEditFieldLabel = uilabel(app.LeftPanel);
            app.LongueurdondenmEditFieldLabel.Text = 'Longueur d''onde (nm)';
            app.LongueurdondenmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongueurdondenmEditField.Value = 1000;
            app.IndicederfractionEditFieldLabel = uilabel(app.LeftPanel);
            app.IndicederfractionEditFieldLabel.Text = 'Indice de réfraction';
            app.IndicederfractionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IndicederfractionEditField.Value = 2.2;
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Absorption')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Absorption')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Courbe d''intensité lum')
            xlabel(app.UIAxes2, 'rayon')
            ylabel(app.UIAxes2, 'intensité')
            zlabel(app.UIAxes2, 'Z')
            app.LancersimuButton = uibutton(app.RightPanel, 'push');
            app.LancersimuButton.ButtonPushedFcn = createCallbackFcn(app, @LancersimuButtonPushed, true);
            app.LancersimuButton.Text = 'Lancer simu';
            app.ResultatsTextAreaLabel = uilabel(app.RightPanel);
            app.ResultatsTextAreaLabel.Text = 'Resultats';
            app.ResultatsTextArea = uitextarea(app.RightPanel);
            app.TracerButton = uibutton(app.RightPanel, 'push');
            app.TracerButton.ButtonPushedFcn = createCallbackFcn(app, @TracerButtonPushed, true);
            app.TracerButton.Text = 'Tracer';
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RightPanel, 'numeric');
            app.YEditField.Limits = [1 200];
            app.YEditField.Value = 1;
            app.ZEditFieldLabel = uilabel(app.RightPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RightPanel, 'numeric');
            app.ZEditField.Limits = [1 200];
            app.ZEditField.Value = 1;
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.Items = {'Gauss annulaire', 'Super Gauss'};
            app.Switch.Value = 'Gauss annulaire';
            app.pEditFieldLabel = uilabel(app.RightPanel);
            app.pEditFieldLabel.Text = 'p : ';
            app.pEditField = uieditfield(app.RightPanel, 'numeric');
            app.pEditField.RoundFractionalValues = 'on';
            app.pEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end